classdef create_basis_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Label_6                         matlab.ui.control.Label
        OptionalLabel                   matlab.ui.control.Label
        RemovePeaksDonotSaveButton      matlab.ui.control.Button
        XMatrixVariableNameEditField    matlab.ui.control.EditField
        XMatrixVariableNameEditFieldLabel  matlab.ui.control.Label
        FTppmVariableNameEditField      matlab.ui.control.EditField
        FTppmVariableNameEditFieldLabel  matlab.ui.control.Label
        CSVTablesVariableNameEditField  matlab.ui.control.EditField
        CSVTablesVariableNameEditFieldLabel  matlab.ui.control.Label
        ReadinVariablesButton           matlab.ui.control.Button
        SaveandRemovePeaksButton        matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        IntensityLessthanDriverEditField  matlab.ui.control.NumericEditField
        IntensityLessthanDriverEditFieldLabel  matlab.ui.control.Label
        CorrelationCutoffEditField      matlab.ui.control.NumericEditField
        CorrelationCutoffEditFieldLabel  matlab.ui.control.Label
        PercentagemaxvalEditField_2     matlab.ui.control.NumericEditField
        PercentagemaxvalEditField_2Label  matlab.ui.control.Label
        rangenextpeakptsEditField_2     matlab.ui.control.NumericEditField
        rangenextpeakptsEditField_2Label  matlab.ui.control.Label
        TraceCorrelatedButton           matlab.ui.control.Button
        Label_4                         matlab.ui.control.Label
        CorrelateButton                 matlab.ui.control.Button
        PercentagemaxvalEditField       matlab.ui.control.NumericEditField
        PercentagemaxvalEditFieldLabel  matlab.ui.control.Label
        rangenextpeakptsEditField       matlab.ui.control.NumericEditField
        rangenextpeakptsEditFieldLabel  matlab.ui.control.Label
        TraceDriverButton               matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        EndppmEditField                 matlab.ui.control.NumericEditField
        EndppmEditFieldLabel            matlab.ui.control.Label
        StartppmExcludeDSSEditField     matlab.ui.control.NumericEditField
        StartppmExcludeDSSppmLabel      matlab.ui.control.Label
        NormpeakendppmEditField         matlab.ui.control.NumericEditField
        NormpeakendppmEditFieldLabel    matlab.ui.control.Label
        NormpeakstartppmEditField       matlab.ui.control.NumericEditField
        NormpeakstartppmEditFieldLabel  matlab.ui.control.Label
        OverallParametersLabel          matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ActionsLabel                    matlab.ui.control.Label
        ParametersLabel                 matlab.ui.control.Label
        CreateBasisSetLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        all_peak_tables % Sand CSV
        X_original % full res ft spectra
        X_removed % Non-full res ft spectra
        ppm_ft % Full res ppm
        ppm_removed % Non-full res ppm
        max_of_X_removed % Max of the current X matrix
        driver_struct % For finding the correlations
        corr_stoc % Correlation vector to the driver
        row % fraction of the max peak
        basis % struct for the basis set
        correlated_traces % Correlated traces to the driver

    end
    
    methods (Access = private)
        
        function adjustDriverFL_app(app,X,ppm,driver_struct)
                            %{
                    Chris Esselman 4.29.25
                    
                    adjustDriverFL
                        Create a 3D stacked plot of fraction libraries with traces for
                        creating a basis set on there. When asked to close the 
                
                    Inputs:
                    
                    X                   Data matrix ex/ 140x32k double
                
                    ppm                 ppm values corresponding to data matrix ex/ 1x32k
                
                    driver_struct       struct containing ppms and fracs. Can be created by
                                        using the tracePeaksFL function
                
                            %}

            % Make the 3D plot
            x = zeros(size(X,1),length(ppm));
            for i = 1:size(X,1)
                x(i,:) = ppm;
            end
            y = X;
            for i = 1:size(X,1)
                z(i,:) = (zeros(1,length(ppm)) + i);
            end
            colors = winter(size(X,1));
            fig = figure('Position',[10 10 1500 900]);
            ax1 = axes(fig);
            hold on
            % Plot the spectra
            for i = 1:size(X,1)
                plot3(x(i,:),z(i,:),y(i,:),'Color',colors(i,:),'LineWidth',0.5)
            end
            view([0 45])
            % Plot the trace
            for i = 1:size(driver_struct,2)
                plot3(ppm(driver_struct(i).ppms),driver_struct(i).fracs,diag(X(driver_struct(i).fracs,driver_struct(i).ppms)),'LineWidth',1.5,'Color','m','LineStyle','-','Marker','.','MarkerSize',18)
            end
            hold off
            set(gca,'XDir','rev');
            originalZLim = zlim(ax1);
            ax1.Clipping = "off";
            z_data_num = 1;
            assignin("base","driver_struct",driver_struct)

            % Doing stuff to make the restore view right
            tb = axtoolbar(ax1,{'datacursor','rotate','pan','zoomin','zoomout'});
            btn = axtoolbarbtn(tb, 'push');
            btn.Icon = 'restoreview';
            btn.ButtonPushedFcn = @(src, event)restoreview(ax1);

            % Create a slider control
            slider = uicontrol('Style', 'slider', 'Min', 0.01, 'Max', 50, 'Value', 1, ...
                'Position', [100, 50, 220, 20], ...
                'Callback', @(src, event) adjustVerticalZoom(ax1, src, z_data_num,originalZLim));
            % slider = uicontrol('Style', 'slider', 'Min', 0.01, 'Max', 100, 'Value', 1, ...
            %                    'Position', [100, 50, 220, 20], ...
            %                    'Callback', @(src, event) adjustVerticalZoom(ax, src, originalZLim));

            % Create a text label for the slider
            sliderLabel = uicontrol('Style', 'text', 'Position', [100, 70, 120, 20], ...
                'String', 'Intensity');

            % Create a button to delete traces
            uicontrol('Style', 'pushbutton', 'String', 'Save Deletions', ...
                'Position', [50, 100, 120, 20], ...
                'Callback', @(src, event)selectRegion(app,X,ppm,driver_struct));

            % Create a button to add traces
            uicontrol('Style', 'pushbutton', 'String', 'Save Additions', ...
                'Position', [200, 100, 120, 20], ...
                'Callback', @(src, event)add_traces(app,X,ppm,driver_struct));
            %% function defs
            function adjustVerticalZoom(ax, slider, z_data_num ,~)
                % Get the current slider value
                zoomFactor = slider.Value;

                % Adjust the upper limit of the z-axis based on the zoom factor
                zData = get(ax.Children, 'ZData');
                if iscell(zData)
                    zData = cell2mat(zData(1+z_data_num:end));
                end
                zMax = max(zData(:));
                baseline = -zMax / zoomFactor/3; % Ensure the baseline is always visible
                zlim(ax, [baseline, zMax / zoomFactor]);
            end
            % Function for deleting
            function selectRegion(app,X,ppm,driver_struct)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                for r = 1:size(vals,2)
                    index = driver_struct.fracs == vals(r).Position(2);
                    driver_struct.ppms(index) = [];
                    driver_struct.fracs(index) = [];
                end
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustDriverFL_app(app,X,ppm,driver_struct);
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            % Function for adding
            function add_traces(app,X,ppm,driver_struct)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                for r = 1:size(vals,2)
                    if sum(ismember(vals(r).Position(2),driver_struct.fracs)) == 0
                        driver_struct.fracs(end + 1) = vals(r).Position(2);
                        driver_struct.ppms(end + 1) = matchPPMs(vals(r).Position(1),ppm);
                    end
                end
                [driver_struct.fracs,I] = sort(driver_struct.fracs,'ascend');
                driver_struct.ppms = driver_struct.ppms(I);
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustDriverFL_app(app,X,ppm,driver_struct);
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            % New callback function for restore view
            function restoreview(ax)
                % Add our own end, which is to reset the axes manually
                set(ax, 'XLimMode', 'auto');
                set(ax, 'YLimMode', 'auto');
                set(ax, 'ZLimMode', 'auto');  % For 3D
                view([0 45])
            end

        end
        
        function adjustCorrelatedFL_app(app,X,ppm,driver_struct,correlated_traces)
                            %{
                    Chris Esselman 4.30.25
                    
                    adjustCorrelatedFL
                        Create a 3D stacked plot of fraction libraries with traces for
                        creating a basis set on there. Have buttons for adding and deleting
                        traces
                
                    Inputs:
                    
                    X                   Data matrix ex/ 140x32k double
                
                    ppm                 ppm values corresponding to data matrix ex/ 1x32k
                
                    driver_struct       struct containing ppms and fracs. Can be created by
                                        using the tracePeaksFL function
                
                    correlated_traces   struct containing ppms of correlated traces to
                                        driver      
                
                            %}

            for i = 1:size(correlated_traces,2)
                if length(correlated_traces(i).ppms) == length(driver_struct.ppms)
                    if correlated_traces(i).ppms == driver_struct.ppms
                        correlated_traces(i) = [];
                        break
                    end
                end
            end
            % Plot how the tracing did
            x = zeros(size(X,1),length(ppm));
            for i = 1:size(X,1)
                x(i,:) = ppm;
            end
            y = X;
            for i = 1:size(X,1)
                z(i,:) = (zeros(1,length(ppm)) + i);
            end
            colors = winter(size(X,1));
            fig = figure('Position',[10 10 1500 900]);
            ax1 = axes(fig);
            hold on
            for i = 1:size(X,1)
                plot3(x(i,:),z(i,:),y(i,:),'Color',colors(i,:),'LineWidth',0.5)
            end
            view([0 45])
            for i = 1:size(driver_struct,2)
                plot3(ppm(driver_struct(i).ppms),driver_struct(i).fracs,diag(X(driver_struct(i).fracs,driver_struct(i).ppms)),'LineWidth',1.5,'Color','m')
            end
            for i = 1:size(correlated_traces,2)
                plot3(ppm(correlated_traces(i).ppms),correlated_traces(i).fracs,diag(X(correlated_traces(i).fracs,correlated_traces(i).ppms)),'LineWidth',1.5,'Color',[0.929 0.694 0.125])
            end
            hold off
            set(gca,'XDir','rev');
            originalZLim = zlim(ax1);
            ax1.Clipping = "off";
            z_data_num = size(correlated_traces,2) + 1;
            % Add correlated traces to the base environment
            correlated_traces2 = correlated_traces;
            correlated_traces2(size(correlated_traces2,2)+1) = driver_struct;
            assignin("base","correlated_traces",correlated_traces2)

            % Doing stuff to make the restore view right
            tb = axtoolbar(ax1,{'datacursor','rotate','pan','zoomin','zoomout'});
            btn = axtoolbarbtn(tb, 'push');
            btn.Icon = 'restoreview';
            btn.ButtonPushedFcn = @(src, event)restoreview(ax1);


            % Create a slider control
            slider = uicontrol('Style', 'slider', 'Min', 0.01, 'Max', 50, 'Value', 1, ...
                'Position', [100, 50, 220, 20], ...
                'Callback', @(src, event) adjustVerticalZoom(ax1, src, z_data_num,originalZLim));
            % slider = uicontrol('Style', 'slider', 'Min', 0.01, 'Max', 100, 'Value', 1, ...
            %                    'Position', [100, 50, 220, 20], ...
            %                    'Callback', @(src, event) adjustVerticalZoom(ax, src, originalZLim));

            % Create a text label for the slider
            sliderLabel = uicontrol('Style', 'text', 'Position', [100, 30, 120, 20], ...
                'String', 'Intensity');

            % Create a button to delete traces
            uicontrol('Style', 'pushbutton', 'String', 'Delete Trace', ...
                'Position', [50, 125, 120, 20], ...
                'Callback', @(src, event)selectRegion(app,X,ppm,driver_struct,correlated_traces));

            % Create a button to add traces to existing trace
            uicontrol('Style', 'pushbutton', 'String', 'Add to Existing Trace', ...
                'Position', [200, 125, 150, 20], ...
                'Callback', @(src, event)add_traces(app,X,ppm,driver_struct,correlated_traces));

            % Create a button to add a new trace
            uicontrol('Style', 'pushbutton', 'String', 'Add New Trace', ...
                'Position', [50, 100, 120, 20], ...
                'Callback', @(src, event)add_trace_new(app,X,ppm,driver_struct,correlated_traces));

            % Create a button to delete all traces below a certain frac
            uicontrol('Style', 'pushbutton', 'String', 'Delete All Trace Below Frac', ...
                'Position', [50, 75, 175, 20], ...
                'Callback', @(src, event)delete_trace_all(app,X,ppm,driver_struct,correlated_traces));

            % Create a button to delete all traces above a certain frac
            uicontrol('Style', 'pushbutton', 'String', 'Delete All Trace Above Frac', ...
                'Position', [250, 75, 175, 20], ...
                'Callback', @(src, event)delete_trace_all_up(app,X,ppm,driver_struct,correlated_traces));

            % Create a button to add all traces between two data markers
            uicontrol('Style', 'pushbutton', 'String', 'Add New Trace Group', ...
                'Position', [200, 100, 150, 20], ...
                'Callback', @(src, event)add_trace_group(app,X,ppm,driver_struct,correlated_traces));

            % Create a button to add all traces between four data markers
            uicontrol('Style', 'pushbutton', 'String', 'Add New Trace Quad', ...
                'Position', [50, 150, 175, 20], ...
                'Callback', @(src, event)add_trace_all_group(app,X,ppm,driver_struct,correlated_traces));

            %% function defs
            function adjustVerticalZoom(ax, slider, z_data_num ,~)
                % Get the current slider value
                zoomFactor = slider.Value;

                % Adjust the upper limit of the z-axis based on the zoom factor
                zData = get(ax.Children, 'ZData');
                if iscell(zData)
                    zData = cell2mat(zData(1+z_data_num:end));
                end
                zMax = max(zData(:));
                baseline = -zMax / zoomFactor/3; % Ensure the baseline is always visible
                zlim(ax, [baseline, zMax / zoomFactor]);
            end
            % Function for deleting
            function selectRegion(app,X,ppm,driver_struct,correlated_traces)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                for r = 1:size(vals,2)
                    index_peak = matchPPMs(vals(r).Position(1),ppm);
                    closest_in_correlated = 0;
                    closest_in_correlated_num = inf;
                    for g = 1:size(correlated_traces,2)
                        for d = 1:length(correlated_traces(g).ppms)
                            if abs(index_peak-correlated_traces(g).ppms(d)) < closest_in_correlated_num
                                closest_in_correlated = g;
                                closest_in_correlated_num = abs(index_peak-correlated_traces(g).ppms(d));
                            end
                        end
                    end
                    index = correlated_traces(closest_in_correlated).fracs == vals(r).Position(2);
                    correlated_traces(closest_in_correlated).ppms(index) = [];
                    correlated_traces(closest_in_correlated).fracs(index) = [];
                end
                % Delete rows that are empty
                correlated_traces(all( cell2mat( arrayfun( @(x) structfun( @isempty, x ), correlated_traces, 'UniformOutput', false ) ), 1 ) ) = [];
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustCorrelatedFL_app(app,X,ppm,driver_struct,correlated_traces)
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            % Function for adding
            function add_traces(app,X,ppm,driver_struct,correlated_traces)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                for r = 1:size(vals,2)
                    index_peak = matchPPMs(vals(r).Position(1),ppm);
                    closest_in_correlated = 0;
                    closest_in_correlated_num = inf;
                    for g = 1:size(correlated_traces,2)
                        for d = 1:length(correlated_traces(g).ppms)
                            if abs(index_peak-correlated_traces(g).ppms(d)) < closest_in_correlated_num
                                closest_in_correlated = g;
                                closest_in_correlated_num = abs(index_peak-correlated_traces(g).ppms(d));
                            end
                        end
                    end
                    if sum(ismember(vals(r).Position(2),correlated_traces(closest_in_correlated).fracs)) == 0
                        correlated_traces(closest_in_correlated).fracs(end + 1) = vals(r).Position(2);
                        correlated_traces(closest_in_correlated).ppms(end + 1) = index_peak;
                        [correlated_traces(closest_in_correlated).fracs,I] = sort(correlated_traces(closest_in_correlated).fracs,'ascend');
                        correlated_traces(closest_in_correlated).ppms = correlated_traces(closest_in_correlated).ppms(I);
                    end
                end
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustCorrelatedFL_app(app,X,ppm,driver_struct,correlated_traces)
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            function add_trace_new(app,X,ppm,driver_struct,correlated_traces)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                if size(vals,2) > 0
                    index_correlated = size(correlated_traces,2);
                    for r = 1:size(vals,2)
                        correlated_traces(index_correlated+1).fracs(r) = vals(r).Position(2);
                        correlated_traces(index_correlated+1).ppms(r) = matchPPMs(vals(r).Position(1),ppm);
                    end
                    [correlated_traces(index_correlated+1).fracs,I] = sort(correlated_traces(index_correlated+1).fracs,'ascend');
                    correlated_traces(index_correlated+1).ppms = correlated_traces(index_correlated+1).ppms(I);
                end
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustCorrelatedFL_app(app,X,ppm,driver_struct,correlated_traces)
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            function add_trace_group(app,X,ppm,driver_struct,correlated_traces)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                % Only consider if there are two data
                if size(vals,2) == 2
                    % Only consider if on the same fraction
                    if vals(1).Position(2) == vals(2).Position(2)
                        % Peak pick the fraction where the max comes from
                        [~,pks_idx_frac] = findpeaks(X(vals(1).Position(2),:));
                        % Indexer so do not need to write for loop
                        indexer = zeros(1,length(ppm));
                        indexer(pks_idx_frac) = 1;
                        % Find where peaks are in between the two data
                        % marks
                        ppm_data_marks = [vals(1).Position(1),vals(2).Position(1)];
                        ppm_data_marks = sort(ppm_data_marks,"descend");
                        indexer(ppm > ppm_data_marks(1) | ppm < ppm_data_marks(2)) = 0;
                        % Convert back to indices instead of logical
                        pks_idx_frac = find(indexer);
                        if ~isempty(pks_idx_frac)
                            % Will all trace starting at same fraction
                            start_frac = zeros(1,length(pks_idx_frac)) + vals(1).Position(2);
                            ppm_range_points = 10;
                            percentage_peak = 10;
                            % Trace all the peaks
                            correlated_traces_before = tracePeaksFL(X,ppm,start_frac,pks_idx_frac,ppm_range_points,percentage_peak,false);
                            orig_cor_size = size(correlated_traces,2);
                            for g = 1:size(correlated_traces_before,2)
                                correlated_traces(orig_cor_size+g).ppms = correlated_traces_before(g).ppms;
                                correlated_traces(orig_cor_size+g).fracs = correlated_traces_before(g).fracs;
                            end
                        end
                    end
                end
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustCorrelatedFL_app(app,X,ppm,driver_struct,correlated_traces)
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            function delete_trace_all(app,X,ppm,driver_struct,correlated_traces)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                % Only consider if there is one data point
                if size(vals,2) == 1
                    frac = vals.Position(2);
                    for g = 1:size(correlated_traces,2)
                        correlated_traces(g).ppms(correlated_traces(g).fracs <= frac) = [];
                        correlated_traces(g).fracs(correlated_traces(g).fracs <= frac) = [];
                    end
                end
                % Delete rows that are empty
                correlated_traces(all( cell2mat( arrayfun( @(x) structfun( @isempty, x ), correlated_traces, 'UniformOutput', false ) ), 1 ) ) = [];
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustCorrelatedFL_app(app,X,ppm,driver_struct,correlated_traces)
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            function delete_trace_all_up(app,X,ppm,driver_struct,correlated_traces)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                % Only consider if there is one data point
                if size(vals,2) == 1
                    frac = vals.Position(2);
                    for g = 1:size(correlated_traces,2)
                        correlated_traces(g).ppms(correlated_traces(g).fracs >= frac) = [];
                        correlated_traces(g).fracs(correlated_traces(g).fracs >= frac) = [];
                    end
                end
                % Delete rows that are empty
                correlated_traces(all( cell2mat( arrayfun( @(x) structfun( @isempty, x ), correlated_traces, 'UniformOutput', false ) ), 1 ) ) = [];
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustCorrelatedFL_app(app,X,ppm,driver_struct,correlated_traces)
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            function add_trace_all_group(app,X,ppm,driver_struct,correlated_traces)
                fig2 = gcf;
                d = datacursormode(fig2);
                vals = getCursorInfo(d);
                % Only consider if there are 4 data markers
                if size(vals,2) == 4
                    % Find the min and max fraction values and ppm values
                    max_frac = -inf;
                    min_frac = inf;
                    max_ppm = -inf;
                    min_ppm = inf;
                    for g = 1:size(vals,2)
                        if vals(g).Position(2) > max_frac
                            max_frac = vals(g).Position(2);
                        end
                        if vals(g).Position(2) < min_frac
                            min_frac = vals(g).Position(2);
                        end
                        if vals(g).Position(1) > max_ppm
                            max_ppm = vals(g).Position(1);
                        end
                        if vals(g).Position(1) < min_ppm
                            min_ppm = vals(g).Position(1);
                        end
                    end     
                    for s = min_frac:max_frac
                        % Peak pick the fraction where the max comes from
                        [~,pks_idx_frac] = findpeaks(X(s,:));
                        % Indexer so do not need to write for loop
                        indexer = zeros(1,length(ppm));
                        indexer(pks_idx_frac) = 1;
                        % Find where peaks are in between the two data
                        % marks
                        ppm_data_marks = [min_ppm,max_ppm];
                        ppm_data_marks = sort(ppm_data_marks,"descend");
                        indexer(ppm > ppm_data_marks(1) | ppm < ppm_data_marks(2)) = 0;
                        % Convert back to indices instead of logical
                        pks_idx_frac = find(indexer);
                        if ~isempty(pks_idx_frac)
                            % Will all trace starting at same fraction
                            start_frac = zeros(1,length(pks_idx_frac)) + s;
                            ppm_range_points = 0;
                            percentage_peak = 10;
                            % Trace all the peaks
                            correlated_traces_before = tracePeaksFL(X,ppm,start_frac,pks_idx_frac,ppm_range_points,percentage_peak,false);
                            orig_cor_size = size(correlated_traces,2);
                            for g = 1:size(correlated_traces_before,2)
                                % Delete parts of traces above or below min
                                % or max frac
                                correlated_traces_before(g).ppms(correlated_traces_before(g).fracs > max_frac) = [];
                                correlated_traces_before(g).fracs(correlated_traces_before(g).fracs > max_frac) = [];
                                correlated_traces_before(g).ppms(correlated_traces_before(g).fracs < min_frac) = [];
                                correlated_traces_before(g).fracs(correlated_traces_before(g).fracs < min_frac) = [];
                                correlated_traces(orig_cor_size+g).ppms = correlated_traces_before(g).ppms;
                                correlated_traces(orig_cor_size+g).fracs = correlated_traces_before(g).fracs;
                            end
                        end
                    end

                end
                xlim_old = xlim(gca);
                ylim_old = ylim(gca);
                zlim_old = zlim(gca);
                [view_old1,view_old2] = view(gca);
                adjustCorrelatedFL_app(app,X,ppm,driver_struct,correlated_traces)
                close(fig2)
                xlim(gca, xlim_old);
                ylim(gca, ylim_old);
                zlim(gca, zlim_old);
                view(gca, [view_old1 view_old2]);  % Optional
            end
            % New callback function for restore view
            function restoreview(ax)
                % Add our own end, which is to reset the axes manually
                set(ax, 'XLimMode', 'auto');
                set(ax, 'YLimMode', 'auto');
                set(ax, 'ZLimMode', 'auto');  % For 3D
                view([0 45])
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadinVariablesButton
        function ReadinVariablesButtonPushed(app, event)
            % Check if the CSV tables is a variable
            if evalin("base",sprintf('exist(''%s'',''var'')',app.CSVTablesVariableNameEditField.Value))
                app.all_peak_tables = evalin("base",app.CSVTablesVariableNameEditField.Value);
                app.CSVTablesVariableNameEditField.BackgroundColor = "g";
            else
                app.CSVTablesVariableNameEditField.BackgroundColor = "r";
            end

            % Check if X matrix is a variable
            if evalin("base",sprintf('exist(''%s'',''var'')',app.XMatrixVariableNameEditField.Value))
                app.X_original = evalin("base",app.XMatrixVariableNameEditField.Value);
                app.XMatrixVariableNameEditField.BackgroundColor = "g";
            else
                app.XMatrixVariableNameEditField.BackgroundColor = "r";
            end

            % Check if ft ppm is a variable
            if evalin("base",sprintf('exist(''%s'',''var'')',app.FTppmVariableNameEditField.Value))
                app.ppm_ft = evalin("base",app.FTppmVariableNameEditField.Value);
                app.FTppmVariableNameEditField.BackgroundColor = "g";
            else
                app.FTppmVariableNameEditField.BackgroundColor = "r";
            end
            
            % Normalize the data
            app.X_removed = normalizeSamples(app.X_original,app.ppm_ft,'integral',[app.NormpeakstartppmEditField.Value,app.NormpeakendppmEditField.Value]);

            % Remove the end regions
            app.X_removed = app.X_removed(:,app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value);
            app.ppm_removed = app.ppm_ft(:,app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value);

        end

        % Button pushed function: TraceDriverButton
        function TraceDriverButtonPushed(app, event)
            ppm_range_points = app.rangenextpeakptsEditField.Value;
            percentage_peak = app.PercentagemaxvalEditField.Value;
            % Find the current max of the data
            [app.max_of_X_removed,lin_indx] = max(app.X_removed,[],"all");
            % Find indices of max
            [app.row,col] = ind2sub([size(app.X_removed,1) size(app.X_removed,2)],lin_indx);

            % Trace that peak
            driver_struct_before = tracePeaksFL(app.X_removed,app.ppm_removed,app.row,col,ppm_range_points,percentage_peak,true);

            % Plot the driver trace and have an option to change the trace
            app.adjustDriverFL_app(app.X_removed,app.ppm_removed,driver_struct_before);

        end

        % Button pushed function: CorrelateButton
        function CorrelateButtonPushed(app, event)
            % Read in the driver to use later
            app.driver_struct = evalin("base","driver_struct");
            
            % Find the max of the driver struct
            % [app.max_of_X_removed,lin_indx] = max(app.X_removed(app.driver_struct.fracs,app.driver_struct.ppms),[],"all");
            % [row2,col] = ind2sub([length(app.driver_struct.fracs) length(app.driver_struct.ppms)],lin_indx);
            % app.row = app.driver_struct.fracs(row2,col);

            % Make a vector that will contain the intensities of the driver
            target_vect = diag(app.X_removed(app.driver_struct.fracs,app.driver_struct.ppms));

            % Do the stocsy
            app.corr_stoc=(zscore(target_vect')*zscore(app.X_removed(app.driver_struct.fracs,:)))./(size(app.X_removed(app.driver_struct.fracs,:),1)-1);
            covar_stoc=(target_vect-mean(target_vect))'*(app.X_removed(app.driver_struct.fracs,:)-repmat(mean(app.X_removed(app.driver_struct.fracs,:)),size(app.X_removed(app.driver_struct.fracs,:),1),1))./(size(app.X_removed(app.driver_struct.fracs,:),1)-1);
            cmap=jet(100);

            lines=NaN(size(cmap,1),size(app.corr_stoc,2));
            ind=1;
            for k=-1:2/size(cmap,1):.99
                lines(ind,find(app.corr_stoc>k))=covar_stoc(find(app.corr_stoc>k));
                ind=ind+1;
            end
            figure('Position',[50 50 1500 800]), plot(app.ppm_removed,lines(1,:),'Color',cmap(1,:),"LineWidth",2)
            hold on
            for k=2:size(cmap,1)
                plot(app.ppm_removed,lines(k,:),'Color',cmap(k,:),"LineWidth",2);
            end
            set(gca,'XDir','rev')
            t=colorbar('colormap',jet(100));
            xlabel('Chemical Shift (ppm)')
            ylabel('Covariance with Driver')
            set(get(t,'ylabel'),'String', ['Correlation with Driver']);
            caxis([-1 1])
            fontsize(16,"points")
        end

        % Button pushed function: TraceCorrelatedButton
        function TraceCorrelatedButtonPushed(app, event)
            % Read in the driver to use later
            app.driver_struct = evalin("base","driver_struct");
            % Find the max of the driver struct
            [app.max_of_X_removed,lin_indx] = max(diag(app.X_removed(app.driver_struct.fracs,app.driver_struct.ppms)'),[],"all");
            [~,col] = ind2sub([1 length(app.driver_struct.fracs)],lin_indx);
            app.row = app.driver_struct.fracs(col);
            % Peak pick the fraction where the max comes from
            [~,pks_idx_frac] = findpeaks(app.X_removed(app.row,:));
            % Indexer so do not need to write for loop
            indexer = zeros(1,length(app.ppm_removed));
            indexer(pks_idx_frac) = 1;
            % Find where peaks are also correlated
            indexer((app.corr_stoc<=app.CorrelationCutoffEditField.Value)) = 0;
            indexer(app.X_removed(app.row,:)<=(app.max_of_X_removed/app.IntensityLessthanDriverEditField.Value)) = 0;
            % Convert back to indices instead of logical
            pks_idx_frac = find(indexer);
            if isempty(pks_idx_frac)
                correlated_traces_before = app.driver_struct;
            else
                % Will all trace starting at same fraction
                start_frac = zeros(1,length(pks_idx_frac)) + app.row;

                ppm_range_points = app.rangenextpeakptsEditField_2.Value;
                percentage_peak = app.PercentagemaxvalEditField_2.Value;

                % Trace all the peaks
                correlated_traces_before = tracePeaksFL(app.X_removed,app.ppm_removed,start_frac,pks_idx_frac,ppm_range_points,percentage_peak,false);
            end
            % Adjust the correlated Traces
            app.adjustCorrelatedFL_app(app.X_removed,app.ppm_removed,app.driver_struct,correlated_traces_before)
            
        end

        % Button pushed function: SaveandRemovePeaksButton
        function SaveandRemovePeaksButtonPushed(app, event)
            h = waitbar(0,"Please Wait for Figure to Appear");
            % Read in the correlated traces to use 
            app.correlated_traces = evalin("base","correlated_traces");
            % Get rid of duplicate
            A = squeeze(struct2cell(app.correlated_traces));
            % Convert each column into a string for comparison
            nCols = size(A, 2);
            colStrs = cell(1, nCols);
            for k = 1:nCols
                % Serialize each column to a string using jsonencode
                colStrs{k} = jsonencode(A(:, k));
            end

            % Find unique columns by their string representation
            [~, uniqueIdx] = unique(colStrs, 'stable');
            app.correlated_traces = app.correlated_traces(uniqueIdx);
            if evalin("base",'exist(''basis'',''var'')')
                app.basis = evalin("base","basis");
            else
                app.basis = struct;
            end
            plotTracesFL(app.X_removed,app.ppm_removed,app.driver_struct,app.correlated_traces)
            title("Before")
            ax1 = gca;
            % Remove the peaks
            [app.X_original,app.all_peak_tables,X_sum] = removePeaksFL(app.X_removed,app.ppm_removed,app.all_peak_tables,app.correlated_traces,[app.StartppmExcludeDSSEditField.Value,app.EndppmEditField.Value]);
            annotation_fraction_plot(app.X_original(:,app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value),app.ppm_ft(app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value))
            title("After")
            ax2 = gca;
            linkaxes([ax1, ax2],'xyz');
            close(h);
            % Create a listener to synchronize the view
            addlistener(ax1, 'View', 'PostSet', @(src,evt) set(ax2, 'View', get(ax1, 'View')));
            addlistener(ax2, 'View', 'PostSet', @(src,evt) set(ax1, 'View', get(ax2, 'View')));
            % Update the basis struct
            if isempty(fieldnames(app.basis))
                app.basis(1).correlated_traces = app.correlated_traces;
                app.basis(1).sum = X_sum;
            else
                size_basis = size(app.basis,2);
                app.basis(size_basis+1).correlated_traces = app.correlated_traces;
                app.basis(size_basis+1).sum = X_sum;
            end
            
            % Save the variables into the base workspace
            assignin("base","basis",app.basis)
            assignin("base","all_peak_tables_removed",app.all_peak_tables)
            assignin("base","X_removed",app.X_original)
            

            % Re-normalize and cutdown regions to conintue onto next
            % element
            % Normalize the data
            app.X_removed = normalizeSamples(app.X_original,app.ppm_ft,'integral',[app.NormpeakstartppmEditField.Value,app.NormpeakendppmEditField.Value]);

            % Remove the end regions
            app.X_removed = app.X_removed(:,app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value);
            app.ppm_removed = app.ppm_ft(:,app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value);


        end

        % Button pushed function: RemovePeaksDonotSaveButton
        function RemovePeaksDonotSaveButtonPushed(app, event)
            h = waitbar(0,"Please Wait for Figure to Appear");
            % Read in the correlated traces to use 
            app.correlated_traces = evalin("base","correlated_traces");
            if evalin("base",'exist(''basis'',''var'')')
                app.basis = evalin("base","basis");
            else
                app.basis = struct;
            end
            plotTracesFL(app.X_removed,app.ppm_removed,app.driver_struct,app.correlated_traces)
            title("Before")
            ax1 = gca;
            % Remove the peaks
            [app.X_original,app.all_peak_tables] = removePeaksFL(app.X_removed,app.ppm_removed,app.all_peak_tables,app.correlated_traces,[app.StartppmExcludeDSSEditField.Value,app.EndppmEditField.Value]);
            annotation_fraction_plot(app.X_original(:,app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value),app.ppm_ft(app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value))
            title("After")
            ax2 = gca;
            linkaxes([ax1, ax2],'xyz');
            close(h);
            % Create a listener to synchronize the view
            addlistener(ax1, 'View', 'PostSet', @(src,evt) set(ax2, 'View', get(ax1, 'View')));
            addlistener(ax2, 'View', 'PostSet', @(src,evt) set(ax1, 'View', get(ax2, 'View')));
            % Update the basis struct
            if isempty(fieldnames(app.basis))
                app.basis(1).removed_traces = app.correlated_traces;
            else
                size_basis = size(app.basis,2);
                app.basis(size_basis+1).removed_traces = app.correlated_traces;   
            end
            
            % Save the variables into the base workspace
            assignin("base","basis",app.basis)
            assignin("base","all_peak_tables_removed",app.all_peak_tables)
            assignin("base","X_removed",app.X_original)
            

            % Re-normalize and cutdown regions to conintue onto next
            % element
            % Normalize the data
            app.X_removed = normalizeSamples(app.X_original,app.ppm_ft,'integral',[app.NormpeakstartppmEditField.Value,app.NormpeakendppmEditField.Value]);

            % Remove the end regions
            app.X_removed = app.X_removed(:,app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value);
            app.ppm_removed = app.ppm_ft(:,app.ppm_ft < app.EndppmEditField.Value & app.ppm_ft > app.StartppmExcludeDSSEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreateBasisSetLabel = uilabel(app.UIFigure);
            app.CreateBasisSetLabel.Text = 'Create Basis Set';
            app.ParametersLabel = uilabel(app.UIFigure);
            app.ParametersLabel.Text = 'Parameters';
            app.ActionsLabel = uilabel(app.UIFigure);
            app.ActionsLabel.Text = 'Actions';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.OverallParametersLabel = uilabel(app.UIFigure);
            app.OverallParametersLabel.Text = '1. Overall Parameters';
            app.NormpeakstartppmEditFieldLabel = uilabel(app.UIFigure);
            app.NormpeakstartppmEditFieldLabel.Text = 'Norm peak start (ppm)';
            app.NormpeakstartppmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NormpeakstartppmEditField.Value = -0.01;
            app.NormpeakendppmEditFieldLabel = uilabel(app.UIFigure);
            app.NormpeakendppmEditFieldLabel.Text = 'Norm peak end (ppm)';
            app.NormpeakendppmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NormpeakendppmEditField.Value = 0.01;
            app.StartppmExcludeDSSppmLabel = uilabel(app.UIFigure);
            app.StartppmExcludeDSSppmLabel.Text = 'Start ppm (Exclude DSS) ';
            app.StartppmExcludeDSSEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartppmExcludeDSSEditField.Value = 0.1;
            app.EndppmEditFieldLabel = uilabel(app.UIFigure);
            app.EndppmEditFieldLabel.Text = 'End ppm';
            app.EndppmEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndppmEditField.Value = 9.1;
            app.Label_3 = uilabel(app.UIFigure);
            app.TraceDriverButton = uibutton(app.UIFigure, 'push');
            app.TraceDriverButton.ButtonPushedFcn = createCallbackFcn(app, @TraceDriverButtonPushed, true);
            app.TraceDriverButton.Text = '3. Trace Driver';
            app.rangenextpeakptsEditFieldLabel = uilabel(app.UIFigure);
            app.rangenextpeakptsEditFieldLabel.Text = 'range next peak (pts)';
            app.rangenextpeakptsEditField = uieditfield(app.UIFigure, 'numeric');
            app.rangenextpeakptsEditField.Limits = [0 Inf];
            app.rangenextpeakptsEditField.RoundFractionalValues = 'on';
            app.rangenextpeakptsEditField.Value = 10;
            app.PercentagemaxvalEditFieldLabel = uilabel(app.UIFigure);
            app.PercentagemaxvalEditFieldLabel.Text = 'Percentage max val';
            app.PercentagemaxvalEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentagemaxvalEditField.Limits = [0 100];
            app.PercentagemaxvalEditField.Value = 10;
            app.CorrelateButton = uibutton(app.UIFigure, 'push');
            app.CorrelateButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelateButtonPushed, true);
            app.CorrelateButton.Text = '4. Correlate';
            app.Label_4 = uilabel(app.UIFigure);
            app.TraceCorrelatedButton = uibutton(app.UIFigure, 'push');
            app.TraceCorrelatedButton.ButtonPushedFcn = createCallbackFcn(app, @TraceCorrelatedButtonPushed, true);
            app.TraceCorrelatedButton.Text = '5. Trace Correlated';
            app.rangenextpeakptsEditField_2Label = uilabel(app.UIFigure);
            app.rangenextpeakptsEditField_2Label.Text = 'range next peak (pts)';
            app.rangenextpeakptsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.rangenextpeakptsEditField_2.Limits = [0 Inf];
            app.rangenextpeakptsEditField_2.RoundFractionalValues = 'on';
            app.rangenextpeakptsEditField_2.Value = 10;
            app.PercentagemaxvalEditField_2Label = uilabel(app.UIFigure);
            app.PercentagemaxvalEditField_2Label.Text = 'Percentage max val';
            app.PercentagemaxvalEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PercentagemaxvalEditField_2.Limits = [0 100];
            app.PercentagemaxvalEditField_2.Value = 10;
            app.CorrelationCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.CorrelationCutoffEditFieldLabel.Text = 'Correlation Cutoff';
            app.CorrelationCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.CorrelationCutoffEditField.Limits = [-1 1];
            app.CorrelationCutoffEditField.Value = 0.99;
            app.IntensityLessthanDriverEditFieldLabel = uilabel(app.UIFigure);
            app.IntensityLessthanDriverEditFieldLabel.Text = 'Intensity Less than Driver';
            app.IntensityLessthanDriverEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntensityLessthanDriverEditField.Limits = [1 Inf];
            app.IntensityLessthanDriverEditField.Value = 9;
            app.Label_5 = uilabel(app.UIFigure);
            app.SaveandRemovePeaksButton = uibutton(app.UIFigure, 'push');
            app.SaveandRemovePeaksButton.ButtonPushedFcn = createCallbackFcn(app, @SaveandRemovePeaksButtonPushed, true);
            app.SaveandRemovePeaksButton.Text = '6. Save and Remove Peaks';
            app.ReadinVariablesButton = uibutton(app.UIFigure, 'push');
            app.ReadinVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadinVariablesButtonPushed, true);
            app.ReadinVariablesButton.Text = '2. Read in Variables';
            app.CSVTablesVariableNameEditFieldLabel = uilabel(app.UIFigure);
            app.CSVTablesVariableNameEditFieldLabel.Text = 'CSV Tables Variable Name';
            app.CSVTablesVariableNameEditField = uieditfield(app.UIFigure, 'text');
            app.CSVTablesVariableNameEditField.Value = 'all_peak_tables';
            app.FTppmVariableNameEditFieldLabel = uilabel(app.UIFigure);
            app.FTppmVariableNameEditFieldLabel.Text = 'FT ppm Variable Name';
            app.FTppmVariableNameEditField = uieditfield(app.UIFigure, 'text');
            app.FTppmVariableNameEditField.Value = 'ppm_ft';
            app.XMatrixVariableNameEditFieldLabel = uilabel(app.UIFigure);
            app.XMatrixVariableNameEditFieldLabel.Text = 'X Matrix Variable Name';
            app.XMatrixVariableNameEditField = uieditfield(app.UIFigure, 'text');
            app.XMatrixVariableNameEditField.Value = 'X_sand';
            app.RemovePeaksDonotSaveButton = uibutton(app.UIFigure, 'push');
            app.RemovePeaksDonotSaveButton.ButtonPushedFcn = createCallbackFcn(app, @RemovePeaksDonotSaveButtonPushed, true);
            app.RemovePeaksDonotSaveButton.Text = 'Remove Peaks. Do not Save';
            app.OptionalLabel = uilabel(app.UIFigure);
            app.OptionalLabel.Text = 'Optional:';
            app.Label_6 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_basis_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end